classdef FinalVolcan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_5                         matlab.ui.control.Label
        VelocidadLabel                  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TiempodevueloLabel              matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        AlcancemaximoLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AlturamximaLabel                matlab.ui.control.Label
        AlturaLabel                     matlab.ui.control.Label
        DistanciaLabel                  matlab.ui.control.Label
        VolcnenMonterreyLabel           matlab.ui.control.Label
        BorrargraficaButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GraficaTab                      matlab.ui.container.Tab
        yluegoobservacomocambiaconresistenciadeaireLabel  matlab.ui.control.Label
        GraficaprimerosinresistenciayluegoLabel  matlab.ui.control.Label
        desalidaEditField               matlab.ui.control.NumericEditField
        desalidaEditFieldLabel          matlab.ui.control.Label
        Entre60y400msLabel              matlab.ui.control.Label
        Entre10y90gradosLabel           matlab.ui.control.Label
        Entre300y4000metrosLabel        matlab.ui.control.Label
        GraficaelVolcnButton            matlab.ui.control.Button
        VdesalidaEditField              matlab.ui.control.NumericEditField
        VdesalidaEditFieldLabel         matlab.ui.control.Label
        AlturadelvolcnEditField         matlab.ui.control.NumericEditField
        AlturadelvolcnEditFieldLabel    matlab.ui.control.Label
        ConresistenciadeaireTab         matlab.ui.container.Tab
        Entre30y30Label                 matlab.ui.control.Label
        VientoexternoEditField          matlab.ui.control.NumericEditField
        VientoexternoEditFieldLabel     matlab.ui.control.Label
        Entre0y4Label                   matlab.ui.control.Label
        Entre0y1Label                   matlab.ui.control.Label
        Entre5y9000kgLabel_2            matlab.ui.control.Label
        Entre10y90gradosLabel_2         matlab.ui.control.Label
        Entre60y400msLabel_2            matlab.ui.control.Label
        Entre300y4000metrosLabel_2      matlab.ui.control.Label
        reatransversalmEditField        matlab.ui.control.NumericEditField
        reatransversalmLabel            matlab.ui.control.Label
        CoefArrastreEditField           matlab.ui.control.NumericEditField
        CoefArrastreEditFieldLabel      matlab.ui.control.Label
        MasaEditField_2                 matlab.ui.control.NumericEditField
        MasaEditField_2Label            matlab.ui.control.Label
        desalidaEditField_2             matlab.ui.control.NumericEditField
        desalidaEditField_2Label        matlab.ui.control.Label
        VdesalidaEditField_2            matlab.ui.control.NumericEditField
        VdesalidaEditField_2Label       matlab.ui.control.Label
        GraficaconresistenciaButton     matlab.ui.control.Button
        AlturadelvolcnEditField_2       matlab.ui.control.NumericEditField
        AlturadelvolcnEditField_2Label  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficaelVolcnButton
        function GraficaelVolcnButtonPushed(app, event)

            A = 0;% area transversal en m cuadrado
            Cd = 0; %coeficiente de arrastre
            m = app.MasaEditField_2.Value; % masa de particula
            if m <= 4
                error3 = 1;
            elseif m >= 9001
                error3 = 1;
            else
                error3 = 0;
            end
            Ta = 20; %temperatura ambiente
            %inicializar
            
            h =  0.1; %paso de tiempo
            t(1) = 0;
            x(1) = 0;
            y(1) = app.AlturadelvolcnEditField.Value;
            if y(1) <= 299
                error = 1;
            elseif y(1) >= 4001
                error = 1;
            else
                error = 0;
            end
            V(1) = app.VdesalidaEditField.Value;
            if V(1) <= 59
                error2 = 1;
            elseif V(1) >= 401
                error2 = 1;
            else
                error2 = 0;
            end
            r(1) = 0;
            ang(1) = app.desalidaEditField.Value;
            if ang(1) <= 9
                error1 = 1;
            elseif ang(1) >= 81
                error1 = 1;
            else
                error1 = 0;
            end
            g = 9.8;
            Vx(1) = V(1)*cosd(ang);
            Vy(1) = V(1)*sind(ang);
            T(1) = 800; %temperatura volcan
            P(1) = 1.2*exp(-4.837e-26*g*y(1)/(1.380649e-23)*T(1));
         
            ax(1) = -(1/2)*P(1)*A*Cd*V(1)*cosd(ang(1))/m;
            ay(1) = (-m*g-(1/2)*P(1)*A*Cd*V(1)*sind(ang(1)))/m;
            
            if error == 1
               errordlg('Ingresa un valor correcto de altura','Altura no permitida');
               return
            end
            if error1 == 1
               errordlg('Ingresa un valor correcto de ángulo','Ángulo no permitido');
               return
            end
            if error2 == 1
               errordlg('Ingresa un valor correcto de velocidad','Velocidad no permitida');
               return
            end
            if error3 == 1
               errordlg('Ingresa un valor correcto de masa','Masa no permitida');
               return
            end
            for i=1:10000 %valor random mil
                
                x(i+1) = x(i)+Vx(i)*h;
                y(i+1) = y(i)+Vy(i)*h;
            
                Vx(i+1)= Vx(i)+ax(i)*h;
                Vy(i+1)= Vy(i)+ay(i)*h;
            
                V(i+1) = sqrt(Vx(i+1)^2+Vy(i+1)^2);
                ang(i+1)= atand(Vy(i+1)/Vx(i+1));
            
                r(i+1) = sqrt((x(i+1)-x(i))^2 + (y(i+1)-y(i))^2);
                T(i+1)=780*exp(-2.597e-3*r(i+1))+Ta;
                P(i+1)= 1.2*exp(-4.837e-26*9.8*y(i+1)/(1.380649e-23)*T(i+1));
                
                ax(i+1) = -(1/2)*P(i+1)*A*Cd*V(i+1)^2*cosd(ang(i+1))/m;
                ay(i+1) = (-m*g-(1/2)*P(i+1)*A*Cd*V(i+1)^2*sind(ang(i+1)))/m;
            
                t(i+1)= t(i)+h;
                app.Label.Text = string(max(y))
                app.Label_3.Text = string(x(i))
                app.Label_4.Text = string(t(i))
                app.Label_5.Text = string(V(i))

                if y(i)<=0
                    break
                end
                plot(app.UIAxes,x,y)
                drawnow
            end
            hold(app.UIAxes,'on');
            %plot(app.UIAxes,x(end), y(end),'.')
            hold(app.UIAxes,'on');
            %comet(app.UIAxes,x,y)
       
        end

        % Button pushed function: GraficaconresistenciaButton
        function GraficaconresistenciaButtonPushed(app, event)
            A = app.reatransversalmEditField.Value;% area transversal en m cuadrado
            if A <= 0
                error4 = 1;
            elseif A >= 4.1
                error4 = 1;
            else
                error4 = 0;
            end
            Cd = app.CoefArrastreEditField.Value; %coeficiente de arrastre
            if Cd <= 0
                error5 = 1;
            elseif Cd >= 1.1
                error5 = 1;
            else
                error5 = 0;
            end
            m = app.MasaEditField_2.Value; % masa de particula
            if m <= 4
                error3 = 1;
            elseif m >= 9001
                error3 = 1;
            else
                error3 = 0;
            end
            Ta = 20; %temperatura ambiente
            Vw = app.VientoexternoEditField.Value;
            if Vw <= -31
                error6 = 1;
            elseif Vw >= 31
                error6 = 1;
            else
                error6 = 0;
            end

            %inicializar
            
            h =  0.1; %paso de tiempo
            t(1) = 0;
            x(1) = 0;
            y(1) = app.AlturadelvolcnEditField_2.Value;
            if y(1) <= 299
                error = 1;
            elseif y(1) >= 4001
                error = 1;
            else
                error = 0;
            end
            V(1) = app.VdesalidaEditField_2.Value;
            if V(1) <= 59
                error2 = 1;
            elseif V(1) >= 401
                error2 = 1;
            else
                error2 = 0;
            end
            r(1) = 0;
            ang(1) = app.desalidaEditField_2.Value;
            if ang(1) <= 9
                error1 = 1;
            elseif ang(1) >= 91
                error1 = 1;
            else
                error1 = 0;
            end
            g = 9.8;
            Vx(1) = V(1)*cosd(ang);
            Vy(1) = V(1)*sind(ang);
            T(1) = 800; %temperatura volcan
            P(1) = 1.2*exp(-4.837e-26*g*y(1)/(1.380649e-23)*T(1));
            
            
            ax(1) = -(1/2)*P(1)*A*Cd*V(1)*cosd(ang(1))/m;
            ay(1) = (-m*g-(1/2)*P(1)*A*Cd*V(1)*sind(ang(1)))/m;
            
            if error == 1
               errordlg('Ingresa un valor correcto de altura','Altura no permitida');
               return
            end
            if error1 == 1
               errordlg('Ingresa un valor correcto de ángulo','Ángulo no permitido');
               return
            end
            if error2 == 1
               errordlg('Ingresa un valor correcto de velocidad','Velocidad no permitida');
               return
            end
            if error3 == 1
               errordlg('Ingresa un valor correcto de masa','Masa no permitida');
               return
            end
            if error4 == 1
               errordlg('Ingresa un valor correcto de área','Área no permitida');
               return
            end
            if error5 == 1
               errordlg('Ingresa un valor correcto de arrastre','Arrastre no permitido');
               return
            end
            if error6 == 1
               errordlg('Ingresa un valor correcto de viento externo','Viento externo no permitido');
               return
            end
          
            for i=1:10000 %valor random mil
                
                x(i+1) = x(i)+Vx(i)*h;
                y(i+1) = y(i)+Vy(i)*h;
            
                Vx(i+1)= Vx(i)+ax(i)*h;
                Vy(i+1)= Vy(i)+ay(i)*h;
            
                V(i+1) = sqrt(Vx(i+1)^2+Vy(i+1)^2);
                ang(i+1)= atand(Vy(i+1)/Vx(i+1));
            
                r(i+1) = sqrt((x(i+1)-x(1))^2 + (y(i+1)-y(1))^2);
                T(i+1)=780*exp(-2.597e-3*r(i+1))+Ta;
                P(i+1)= 1.2*exp(-4.837e-26*9.8*y(i+1)/((1.380649e-23)*T(i+1)));
                
                if Vw < 0
                    ax(i+1) = -(1/2)*P(i+1)*A*Cd*V(i+1)^2*cosd(ang(i+1))/m + (-(1/2)*P(i+1)*A*Cd*Vw^2)/m;
                else
                    ax(i+1) = -(1/2)*P(i+1)*A*Cd*V(i+1)^2*cosd(ang(i+1))/m - (-(1/2)*P(i+1)*A*Cd*Vw^2)/m;
                end

                
                ay(i+1) = (-m*g-(1/2)*P(i+1)*A*Cd*V(i+1)^2*sind(ang(i+1)))/m;
            
                t(i+1)= t(i)+h;
                app.Label.Text = string(max(y))
                app.Label_3.Text = string(x(i))
                app.Label_4.Text = string(t(i))
                app.Label_5.Text = string(V(i))
            
                if y(i)<=0
                    break
                end
                plot(app.UIAxes,x,y)
                drawnow
            end
          
            hold(app.UIAxes,'on');
            %plot(app.UIAxes,x(end), y(end),'.')
            hold(app.UIAxes,'on');
            %comet(app.UIAxes,x,y)
           
        end

        % Button pushed function: BorrargraficaButton
        function BorrargraficaButtonPushed(app, event)
            cla(app.UIAxes,'reset')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'alto')
            colormap(app.UIAxes, 'turbo')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficaTab = uitab(app.TabGroup);
            app.GraficaTab.Title = 'Grafica';
            app.AlturadelvolcnEditFieldLabel = uilabel(app.GraficaTab);
            app.AlturadelvolcnEditFieldLabel.Text = 'Altura del volcán';
            app.AlturadelvolcnEditField = uieditfield(app.GraficaTab, 'numeric');
            app.AlturadelvolcnEditField.Value = 1000;
            app.VdesalidaEditFieldLabel = uilabel(app.GraficaTab);
            app.VdesalidaEditFieldLabel.Text = 'V de salida';
            app.VdesalidaEditField = uieditfield(app.GraficaTab, 'numeric');
            app.VdesalidaEditField.Value = 70;
            app.GraficaelVolcnButton = uibutton(app.GraficaTab, 'push');
            app.GraficaelVolcnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaelVolcnButtonPushed, true);
            app.GraficaelVolcnButton.Text = 'Grafica el Volcán';
            app.Entre300y4000metrosLabel = uilabel(app.GraficaTab);
            app.Entre300y4000metrosLabel.Text = 'Entre 300 y 4000 metros';
            app.Entre10y90gradosLabel = uilabel(app.GraficaTab);
            app.Entre10y90gradosLabel.Text = 'Entre 10 y 90 grados';
            app.Entre60y400msLabel = uilabel(app.GraficaTab);
            app.Entre60y400msLabel.Text = 'Entre 60 y 400 m/s';
            app.desalidaEditFieldLabel = uilabel(app.GraficaTab);
            app.desalidaEditFieldLabel.Text = 'θ de salida';
            app.desalidaEditField = uieditfield(app.GraficaTab, 'numeric');
            app.desalidaEditField.Value = 45;
            app.GraficaprimerosinresistenciayluegoLabel = uilabel(app.GraficaTab);
            app.GraficaprimerosinresistenciayluegoLabel.Text = 'Grafica primero sin resistencia  ';
            app.yluegoobservacomocambiaconresistenciadeaireLabel = uilabel(app.GraficaTab);
            app.yluegoobservacomocambiaconresistenciadeaireLabel.Text = 'y luego observa como cambia con resistencia de aire!!';
            app.ConresistenciadeaireTab = uitab(app.TabGroup);
            app.ConresistenciadeaireTab.Title = 'Con resistencia de aire';
            app.AlturadelvolcnEditField_2Label = uilabel(app.ConresistenciadeaireTab);
            app.AlturadelvolcnEditField_2Label.Text = 'Altura del volcán';
            app.AlturadelvolcnEditField_2 = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.AlturadelvolcnEditField_2.Value = 1000;
            app.GraficaconresistenciaButton = uibutton(app.ConresistenciadeaireTab, 'push');
            app.GraficaconresistenciaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaconresistenciaButtonPushed, true);
            app.GraficaconresistenciaButton.Text = 'Grafica con resistencia';
            app.VdesalidaEditField_2Label = uilabel(app.ConresistenciadeaireTab);
            app.VdesalidaEditField_2Label.Text = 'V de salida';
            app.VdesalidaEditField_2 = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.VdesalidaEditField_2.Value = 70;
            app.desalidaEditField_2Label = uilabel(app.ConresistenciadeaireTab);
            app.desalidaEditField_2Label.Text = 'θ de salida';
            app.desalidaEditField_2 = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.desalidaEditField_2.Value = 45;
            app.MasaEditField_2Label = uilabel(app.ConresistenciadeaireTab);
            app.MasaEditField_2Label.Text = 'Masa';
            app.MasaEditField_2 = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.MasaEditField_2.Value = 50;
            app.CoefArrastreEditFieldLabel = uilabel(app.ConresistenciadeaireTab);
            app.CoefArrastreEditFieldLabel.Text = 'Coef Arrastre';
            app.CoefArrastreEditField = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.CoefArrastreEditField.Value = 0.5;
            app.reatransversalmLabel = uilabel(app.ConresistenciadeaireTab);
            app.reatransversalmLabel.Text = 'Área transversal (m²)';
            app.reatransversalmEditField = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.reatransversalmEditField.Value = 0.7;
            app.Entre300y4000metrosLabel_2 = uilabel(app.ConresistenciadeaireTab);
            app.Entre300y4000metrosLabel_2.Text = 'Entre 300 y 4000 metros';
            app.Entre60y400msLabel_2 = uilabel(app.ConresistenciadeaireTab);
            app.Entre60y400msLabel_2.Text = 'Entre 60 y 400 m/s';
            app.Entre10y90gradosLabel_2 = uilabel(app.ConresistenciadeaireTab);
            app.Entre10y90gradosLabel_2.Text = 'Entre 10 y 90 grados';
            app.Entre5y9000kgLabel_2 = uilabel(app.ConresistenciadeaireTab);
            app.Entre5y9000kgLabel_2.Text = 'Entre 5 y 9000 kg';
            app.Entre0y1Label = uilabel(app.ConresistenciadeaireTab);
            app.Entre0y1Label.Text = 'Entre 0 y 1';
            app.Entre0y4Label = uilabel(app.ConresistenciadeaireTab);
            app.Entre0y4Label.Text = 'Entre 0 y 4';
            app.VientoexternoEditFieldLabel = uilabel(app.ConresistenciadeaireTab);
            app.VientoexternoEditFieldLabel.Text = 'Viento externo';
            app.VientoexternoEditField = uieditfield(app.ConresistenciadeaireTab, 'numeric');
            app.Entre30y30Label = uilabel(app.ConresistenciadeaireTab);
            app.Entre30y30Label.Text = 'Entre -30 y 30';
            app.BorrargraficaButton = uibutton(app.UIFigure, 'push');
            app.BorrargraficaButton.ButtonPushedFcn = createCallbackFcn(app, @BorrargraficaButtonPushed, true);
            app.BorrargraficaButton.Text = 'Borrar grafica';
            app.VolcnenMonterreyLabel = uilabel(app.UIFigure);
            app.VolcnenMonterreyLabel.Text = 'Volcán en Monterrey';
            app.DistanciaLabel = uilabel(app.UIFigure);
            app.DistanciaLabel.Text = 'Distancia';
            app.AlturaLabel = uilabel(app.UIFigure);
            app.AlturaLabel.Text = 'Altura';
            app.AlturamximaLabel = uilabel(app.UIFigure);
            app.AlturamximaLabel.Text = 'Altura máxima ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AlcancemaximoLabel = uilabel(app.UIFigure);
            app.AlcancemaximoLabel.Text = 'Alcance maximo';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.TiempodevueloLabel = uilabel(app.UIFigure);
            app.TiempodevueloLabel.Text = 'Tiempo de vuelo';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.VelocidadLabel = uilabel(app.UIFigure);
            app.VelocidadLabel.Text = 'Velocidad';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalVolcan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end